classdef MagField_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        campo                           matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        CampomagnticoTLabel             matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        VistaTransversalTab             matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        Vista3DTab                      matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        IngresaelpuntoenquedeseassaberlamagnituddelcampoLabel  matlab.ui.control.Label
        NormalizacindevectoresCheckBox  matlab.ui.control.CheckBox
        GraficarButton                  matlab.ui.control.Button
        VisualizacinLabel               matlab.ui.control.Label
        I                               matlab.ui.control.NumericEditField
        IntensidaddecorrienteAEditFieldLabel  matlab.ui.control.Label
        Radio                           matlab.ui.control.NumericEditField
        RadiodelaespiramEditFieldLabel  matlab.ui.control.Label
        ParmetrosdelaespiraLabel        matlab.ui.control.Label
        CampoMagnticoEnUnaEspiraLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
                
                %% Graciación transveral
                
                cla(app.UIAxes)
                radio = app.Radio.Value;
                lim = radio+10;
                %% Creación de dimensiones
                y = linspace(-lim,lim,105);
                z = linspace(-lim,lim,105);
                [yp,zp] =meshgrid(y,z);
                %% Definición de variables
                corriente = app.I.Value;
                %Definición de constantes
                u0=(4*pi)*10^-7;
                %% Definición de coordenas del círculo
                angulo = 0:pi/20:2*pi-pi/20;
                X = radio*cos(angulo);
                Y  = radio*sin(angulo);
              
                %Creación de vectores para valores de campo magnético
                By = zeros(length(yp));
                Bz = zeros(length(zp));
                Bt = zeros(length(yp));
                
                %Metodo iterativo para calcular y almacenar el campo
                %mágnetico
                for j = 1:length(By)
                    for i = 1:length(By)
                        for k =1:length(angulo)
                            %Bloque condicional, para evitar pasar el rango
                            %definido, dado a que el diferncial se defino
                            %como k+1.
                            if k==length(angulo)
                                %Definición de vectores de posición
                                R_x = (-X(k)-X(1))/2; 
                                R_y = yp(i,j)-(Y(k)+Y(1))/2;
                                %Definición de diferenciales
                                Lx = X(1)-X(k);
                                Ly = Y(1)-Y(k);
                            else
                                R_x = (-X(k)-X(k+1))/2;
                                R_y = yp(i,j)-(Y(k)+Y(k+1))/2;
                                Lx = X(k+1)-X(k);
                                Ly = Y(k+1)-Y(k);
                            end
                            
                            R_z = zp(i, j);            
                            vecR = [R_x R_y R_z]; %Matriz con vectores de posición
                            R = sqrt(sum(vecR.^2));
                            Lz = 0;
                                
                            vecL = [Lx Ly Lz]; %Matriz con diferenciales
                            vecB = cross(vecL, vecR).*((u0*corriente)/(4*pi*R^3)); %Producto cruz entre vectores
                            
                            %Almacenamiento de resultados para cada
                            %iteración
                            By(i,j) = By(i,j) + vecB(2);
                            Bz(i,j) = Bz(i,j) + vecB(3);
                            Bt(i,j) = sqrt(Bz(i,j)^2 + By(i,j)^2);
                        end
                    end
                end
                %Normalización de componentes
                u = By./Bt;
                v = Bz./Bt; 
                
                
                %Visualización de componentes utilizando plot para la
                %espira y quiver.
                cla(app.UIAxes)
                espiraT= plot(app.UIAxes,[-radio,radio], [0,0]);
                set(espiraT,'color',[0 0 1], 'linewidth',3);
                hold(app.UIAxes,"on")
                axis(app.UIAxes, [-radio radio -radio radio])
                
                %Revisa si el usuario desea normalización de vectores y
                %despliga los vectores necesario
                if app.NormalizacindevectoresCheckBox.Value == 1
                    vector = quiver(app.UIAxes,yp,zp,u,v,0.65);
                    

                end
                
                if app.NormalizacindevectoresCheckBox.Value ==0
                    vector = quiver(app.UIAxes,yp,zp,By,Bz,0.65);
                  
                end
                set(vector,'color',[0 0 0], 'linewidth',1.35);
                axis(app.UIAxes,"equal");
                title(app.UIAxes,'Campo magnético de una espira')


                %% Graficación en 3D
                %Se define un nuevo mesh grid de 3 dimensiones
                Puntos = 25;
                x = linspace(-lim,lim,Puntos);
                y  = linspace(-lim,lim,Puntos);
                z  = linspace(-lim,lim,Puntos);
                [xp,yp,zp] =meshgrid(x,y,z);
                
                %Se define un vector de zeros para la coordenada de Z del
                %círculo
                Z = zeros(length(X));
                Bx = zeros(length(xp),length(xp),length(xp));
                By = zeros(length(yp),length(yp),length(yp));
                Bz = zeros(length(zp),length(zp),length(zp));
                Bt = zeros(length(yp),length(yp),length(yp));
                
                
                %Evaluación de diferencial
                for i = 1:length(By)
                    for j = 1:length(By)
                        for k = 1:length(By)
                            for l =1:length(angulo)
                                if l==length(angulo)
                                    R_x = (-X(l)-X(1))/2;
                                    R_y = yp(i,j,k)-(Y(l)+Y(1))/2;
                                    Lx = X(1)-X(l);
                                    Ly = Y(1)-Y(l);
                                else
                                    R_x = (-X(l)-X(l+1))/2;
                                    R_y = yp(i,j,k)-(Y(l)+Y(l+1))/2;
                                    Lx = X(l+1)-X(l);
                                    Ly = Y(l+1)-Y(l);
                                end
                                R_z = zp(i, j,k);            
                                vecR = [R_x R_y R_z];
                                R = sqrt(sum(vecR.^2));
                                Lz = 0;
                                    
                                vecL = [Lx Ly Lz];
                                vecB = cross(vecL, vecR).*((u0*corriente)/(4*pi*R^3));
                                
                                Bx(i,j,k) = Bx(i,j,k) + vecB(1);
                                By(i,j,k) = By(i,j,k) + vecB(2);
                                Bz(i,j,k) = Bz(i,j,k) + vecB(3);
                                Bt(i,j,k) = sqrt(Bz(i,j,k)^2 + By(i,j)^2);
                                
                            end
                        end
                    end
                end
                
                %Normalización de componentes
                u = Bx./Bt;
                v = By./Bt;
                w = Bz./Bt;
                
                %Grafiación de resultados utilizando plot y quiver 3D
                cla(app.UIAxes2)
                espira = plot3(app.UIAxes2,X,Y,Z);
                set(espira,'color',[0 0 1], 'linewidth',3);
                hold(app.UIAxes2,"on")
                
                if app.NormalizacindevectoresCheckBox.Value == 1
                    vector = quiver3(app.UIAxes2,xp,yp,zp,u,v,w,1);
                            
                end
                        
                if app.NormalizacindevectoresCheckBox.Value ==0
                    vector = quiver3(app.UIAxes2,xp,yp,zp,Bx,By,Bz,1);
                end
                set(vector,'color',[0 0 0], 'linewidth',1.35);
                axis(app.UIAxes2,"equal");
                title(app.UIAxes2,'Campo magnético de una espira')
                axis(app.UIAxes2,[-radio radio -radio radio -5 5]);
                title(app.UIAxes2,'Campo magnético de una espira')
                
                
                %%Evaluación
                PuntoZ = app.ZEditField.Value;
                PuntoY = app.YEditField.Value;
                Byp = 0;
                Bzp = 0;
                Btp = 0;
                for k =1:length(angulo)
                        if k==length(angulo)
                            R_x = (-X(k)-X(1))/2;
                            R_y = PuntoY-(Y(k)+Y(1))/2;
                            Lx = X(1)-X(k);
                            Ly = Y(1)-Y(k);
                        else
                            R_x = (-X(k)-X(k+1))/2;
                            R_y = PuntoY-(Y(k)+Y(k+1))/2;
                            Lx = X(k+1)-X(k);
                            Ly = Y(k+1)-Y(k);
                        end
                        R_z = PuntoZ;            
                        vecR = [R_x R_y R_z];
                        R = sqrt(sum(vecR.^2));
                        Lz = 0;
                
                        vecL = [Lx Ly Lz];
                        vecB = cross(vecL, vecR).*((u0*corriente)/(4*pi*R^3));
                
                        Byp = Byp + vecB(2);
                        Bzp = Bzp + vecB(3);
                        Btp = Btp + sqrt(Bzp^2 + Byp^2);
                end
                
                app.campo.Text = num2str(Btp);
                drawnow


        end

        % Callback function
        function DButtonPushed(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CampoMagnticoEnUnaEspiraLabel = uilabel(app.UIFigure);
            app.CampoMagnticoEnUnaEspiraLabel.Text = 'Campo Magnético En Una Espira';
            app.ParmetrosdelaespiraLabel = uilabel(app.UIFigure);
            app.ParmetrosdelaespiraLabel.Text = 'Parámetros de la espira';
            app.RadiodelaespiramEditFieldLabel = uilabel(app.UIFigure);
            app.RadiodelaespiramEditFieldLabel.Text = 'Radio de la espira (m)';
            app.Radio = uieditfield(app.UIFigure, 'numeric');
            app.Radio.Value = 2;
            app.IntensidaddecorrienteAEditFieldLabel = uilabel(app.UIFigure);
            app.IntensidaddecorrienteAEditFieldLabel.Text = 'Intensidad de corriente (A)';
            app.I = uieditfield(app.UIFigure, 'numeric');
            app.I.Value = 35;
            app.VisualizacinLabel = uilabel(app.UIFigure);
            app.VisualizacinLabel.Text = 'Visualización';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.NormalizacindevectoresCheckBox = uicheckbox(app.UIFigure);
            app.NormalizacindevectoresCheckBox.Text = ' Normalización de vectores';
            app.IngresaelpuntoenquedeseassaberlamagnituddelcampoLabel = uilabel(app.UIFigure);
            app.IngresaelpuntoenquedeseassaberlamagnituddelcampoLabel.Text = 'Ingresa el punto en que deseas saber la magnitud del campo';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VistaTransversalTab = uitab(app.TabGroup);
            app.VistaTransversalTab.Title = 'Vista Transversal';
            app.UIAxes = uiaxes(app.VistaTransversalTab);
            title(app.UIAxes, 'Vista Transversal')
            xlabel(app.UIAxes, 'Y')
            ylabel(app.UIAxes, 'Z')
            zlabel(app.UIAxes, 'Z')
            app.Vista3DTab = uitab(app.TabGroup);
            app.Vista3DTab.Title = 'Vista 3D';
            app.UIAxes2 = uiaxes(app.Vista3DTab);
            title(app.UIAxes2, 'Vista 3D')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CampomagnticoTLabel = uilabel(app.UIFigure);
            app.CampomagnticoTLabel.Text = 'Campo magnético (T): ';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Value = 2;
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z:';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.Value = 4;
            app.campo = uilabel(app.UIFigure);
            app.campo.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MagField_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end