classdef SimuladorFINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Equipo4Label                   matlab.ui.control.Label
        timeE                          matlab.ui.control.Label
        TiempodeejecucinsLabel         matlab.ui.control.Label
        StatusLabel                    matlab.ui.control.Label
        InicioLabel                    matlab.ui.control.Label
        SimularPoraproximacinButton    matlab.ui.control.Button
        SimularPorintegracinButton     matlab.ui.control.Button
        xn                             matlab.ui.control.NumericEditField
        PosicinenXEditField_2Label     matlab.ui.control.Label
        Dn                             matlab.ui.control.NumericEditField
        DensidaddecargaCMEditField_2Label  matlab.ui.control.Label
        Ln                             matlab.ui.control.NumericEditField
        LongitudMEditField_2Label      matlab.ui.control.Label
        CableNegativoLabel             matlab.ui.control.Label
        CablePositivoLabel             matlab.ui.control.Label
        Xqp                            matlab.ui.control.NumericEditField
        PosicinenXEditFieldLabel       matlab.ui.control.Label
        Dp                             matlab.ui.control.NumericEditField
        Lp                             matlab.ui.control.NumericEditField
        LongitudMEditFieldLabel        matlab.ui.control.Label
        DensidaddecargaCMEditFieldLabel  matlab.ui.control.Label
        SimuladordeCampoElctricoLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularPorintegracinButton
        function SimularPorintegracinButtonPushed(app, event)
   
            tic
            % Creación de dimensiones
            Puntos = 45; 
            x0 = linspace(-20,20,Puntos);
            y0 = linspace(-20,20, Puntos);
            
            [xp,yp] =meshgrid(x0,y0);
            
            %% Definimos lo cables
            ke = 8.9875*10^9;
            %Carga negativa
            DensidadN = app.Dn.Value;
            DensidadP = app.Dp.Value;
                
            Q_n_x = app.xn.Value;
            Q_p_x = app.Xqp.Value;
            LongitudP = app.Lp.Value;
            LongitudN = app.Ln.Value;
            
            if LongitudN > LongitudP
                LongitudPositiva = [(LongitudN/2)-(LongitudP/2):0.2:LongitudP+((LongitudN/2)-(LongitudP/2))];
                LongitudNegativa = [0:0.2:LongitudN];
            end
                    
            if LongitudN < LongitudP
                 LongitudNegativa = [(LongitudP/2)-(LongitudN/2):0.2:LongitudN+((LongitudP/2)-(LongitudN/2))];
                 LongitudPositiva = [0:0.07:LongitudP];
            end
                    
            if LongitudP == LongitudN
                LongitudPositiva = [0:0.2:LongitudP];
                LongitudNegativa = [0:0.2:LongitudN];
                        
            end
            
            Q_ny = LongitudNegativa;
            Q_py = LongitudPositiva;
            
            LimInfP = LongitudPositiva(1);
            LimSupP = LongitudPositiva(length(LongitudPositiva));
            
            LimInfN = LongitudNegativa(1);
            LimSupN = LongitudNegativa(length(LongitudNegativa));
             
            En_x =zeros(Puntos);
            En_y =zeros(Puntos);
            
            app.InicioLabel.Text ='Calculando campo negativo';
            drawnow
            for i = 1:length(x0)
                for k=1:length(y0)
                    CampoEnx = @(y) (ke*(x0(i) - Q_n_x)*DensidadN)./(sqrt((x0(i) - Q_n_x).^2+(y0(k) - y).^2)).^3;
                    CampoEny = @(y) (ke.*DensidadN.*(y0(k)-y))./(sqrt((x0(i) - Q_n_x).^2+(y0(k) - y).^2)).^3;
            
                    En_x(k,i)=  (integral(CampoEnx,LimInfN,LimSupN));
                    En_y(k,i) = (integral(CampoEny,LimInfN,LimSupN));
                    
                end
            end
            app.InicioLabel.Text ='Calculando campo positivo';
            drawnow
            Ep_x =zeros(Puntos);
            Ep_y =zeros(Puntos);
            
            for i = 1:length(x0)
                for k=1:length(y0)
                    CampoEpx = @(y) (ke*(x0(i) - Q_p_x)*DensidadP)./(sqrt((x0(i) - Q_p_x).^2+(y0(k) - y).^2)).^3;
                    CampoEpy = @(y) (ke.*DensidadP.*(y0(k)-y))./(sqrt((x0(i) - Q_p_x).^2+(y0(k) - y).^2)).^3;
            
                    Ep_x(k,i)=  (integral(CampoEpx,LimInfP,LimSupP));
                    Ep_y(k,i) = (integral(CampoEpy,LimInfP,LimSupP));
                    
                end
            end
            
            
            Et_x = En_x+Ep_x; 
            Et_y = En_y +Ep_y;
            
            Et = sqrt(Et_x.^2+Et_y.^2);
            
            %Se normalizan los componentes, para poder tenerlos en forma unitaria
            u = Et_x./Et;
            v = Et_y./Et;
            
            %% Graficación
            %Graficación del campo eléctrico utilizando quiver
            vector = quiver(app.UIAxes,xp,yp,u,v,0.5);
            set(vector,'color',[0 0 0], 'linewidth',1.35);
            axis(app.UIAxes,[-20 20 -20 20]);
            axis(app.UIAxes,"equal");
            grid(app.UIAxes,"on")
            pbaspect(app.UIAxes,[1 1 1])
            title(app.UIAxes,'Campo Eléctrico de un Dipolo')
            legend(app.UIAxes,'Vectores de Campo Eléctrico')
            
            diametro =0.85;
            app.InicioLabel.Text ='Graficando cable negativo';
            drawnow
            for i = 1:length(Q_ny)
              rectangle(app.UIAxes,'Position',[(Q_n_x -diametro/2) (Q_ny(i)-diametro/2) diametro diametro],'Curvature',[1,1], 'FaceColor', 'b', 'EdgeColor', 'b');
              text(app.UIAxes,Q_n_x-.2,Q_ny(i)+.2,'-','Color','white','FontSize',15) 
            end
            
            app.InicioLabel.Text ='Graficando cable Positivo';
            drawnow
            for i = 1:length(Q_py)
               rectangle(app.UIAxes,'Position',[(Q_p_x -diametro/2) (Q_py(i)-diametro/2) diametro diametro],'Curvature',[1,1], 'FaceColor', 'r', 'EdgeColor', 'r');
               text(app.UIAxes,Q_p_x-.2,Q_py(i)+.2,'+','Color','white','FontSize',15)
               
            end
            
            app.InicioLabel.Text ='Termina';
            drawnow
            time =toc;
            app.timeE.Text = num2str(time);
            drawnow
            
            
        end

        % Button pushed function: SimularPoraproximacinButton
        function SimularPoraproximacinButtonPushed(app, event)
             tic   
            %% Creación de dimensiones
            Puntos = 45; 
            x = linspace(-20,20,Puntos);
            y = linspace(-20,20, Puntos);
            
            [xp,yp] =meshgrid(x,y);
             
            %% Definición de cargas
            MedidaP = app.Lp.Value;
            MedidaN = app.Ln.Value;
            
            if MedidaN > MedidaP
                LongitudPositiva = [(MedidaN/2)-(MedidaP/2):0.2:MedidaP+((MedidaN/2)-(MedidaP/2))];
                LongitudNegativa = [0:0.2:MedidaN];
            end
                    
            if MedidaN < MedidaP
                 LongitudNegativa = [(MedidaP/2)-(MedidaN/2):0.2:MedidaN+((MedidaP/2)-(MedidaN/2))];
                 LongitudPositiva = [0:0.2:MedidaP];
            end
                    
            if MedidaP == MedidaN
                LongitudPositiva = [0:0.2:MedidaP];
                LongitudNegativa = [0:0.2:MedidaN];
                        
            end
  
            
            %Carga negativa
            Q_n = app.Dn.Value; %Carga en Coulombs
            Q_nx = app.xn.Value;
            
            %Carga positiva
            Q_p = app.Dp.Value; %Carga en Coulombs
            Q_px = app.Xqp.Value;
            
            diametro = .85;
            %% Definición de parámetros de campo eléctrico
            %Constante
            ke = 8.9875*10^9;
            %Campo de cargas negativas
            En_x = 0;
            En_y = 0;
            for i = 1:length(LongitudNegativa)
                %Vector de posición 
                R1_x = xp-Q_nx;
                R1_y = yp-LongitudNegativa(i);
                R1 = sqrt(R1_x.^2 +R1_y.^2);
            
                En_x = En_x +((ke.*(Q_n./(R1.^3))).*R1_x);
                En_y = En_y +((ke.*(Q_n./(R1.^3))).*R1_y);
            
            end
            app.InicioLabel.Text ='Calculando Campo negativo';
            %Campo de cargas postivas
            Ep_x = 0;
            Ep_y = 0;
            for i =1:length(LongitudPositiva)
                %Vector de posición 
                R2_x = xp-Q_px;
                R2_y = yp-LongitudPositiva(i);
                R2 = sqrt(R2_x.^2 +R2_y.^2);
            
                %Campo de carga positiva
                Ep_x = Ep_x +((ke.*(Q_p./(R2.^3))).*R2_x);
                Ep_y = Ep_y +((ke.*(Q_p./(R2.^3))).*R2_y);
            end
            app.InicioLabel.Text ='Calculando Campo Positivo';
            drawnow

            %Campo total
            Et_x = En_x+Ep_x; 
            Et_y = En_y +Ep_y;
            
            Et = sqrt(Et_x.^2+Et_y.^2);
            
            %Se normalizan los componentes, para poder tenerlos en forma unitaria
            u = Et_x./Et;
            v = Et_y./Et;
            
            %% Graficación
            %Graficación del campo eléctrico utilizando quiver
            vector = quiver(app.UIAxes,xp,yp,u,v,0.5);
            set(vector,'color',[0 0 0], 'linewidth',1.35);
            axis(app.UIAxes,[-20 20 -20 20]);
            axis(app.UIAxes,"equal");
            grid(app.UIAxes,"on")
            pbaspect(app.UIAxes,[1 1 1])
            title(app.UIAxes,'Campo Eléctrico de un Dipolo')
            
            
            for i = 1:length(LongitudPositiva)
               rectangle(app.UIAxes,'Position',[(Q_px -diametro/2) (LongitudPositiva(i)-diametro/2) diametro diametro],'Curvature',[1,1], 'FaceColor', 'r', 'EdgeColor', 'r');
               text(app.UIAxes,Q_px-.2,LongitudPositiva(i)+.2,'+','Color','white','FontSize',15)
               
            end
            app.InicioLabel.Text ='Graficando cable positivo';
            
            
            for i = 1:length(LongitudNegativa)
              rectangle(app.UIAxes,'Position',[(Q_nx -diametro/2) (LongitudNegativa(i)-diametro/2) diametro diametro],'Curvature',[1,1], 'FaceColor', 'b', 'EdgeColor', 'b');
              text(app.UIAxes,Q_nx-.2,LongitudNegativa(i)+.2,'-','Color','white','FontSize',15) 
            end
            legend(app.UIAxes,'Vectores de Campo Eléctrico')
           
            app.InicioLabel.Text ='Graficando cable negativo';
            drawnow
            
            app.InicioLabel.Text ='Termina';
            drawnow
            time =toc;
            app.timeE.Text = num2str(time);
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Campo Eléctrico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SimuladordeCampoElctricoLabel = uilabel(app.UIFigure);
            app.SimuladordeCampoElctricoLabel.Text = 'Simulador de Campo Eléctrico';
            app.DensidaddecargaCMEditFieldLabel = uilabel(app.UIFigure);
            app.DensidaddecargaCMEditFieldLabel.Text = 'Densidad de carga(C/M)';
            app.LongitudMEditFieldLabel = uilabel(app.UIFigure);
            app.LongitudMEditFieldLabel.Text = 'Longitud(M)';
            app.Lp = uieditfield(app.UIFigure, 'numeric');
            app.Lp.Limits = [-20 20];
            app.Lp.Value = 4;
            app.Dp = uieditfield(app.UIFigure, 'numeric');
            app.Dp.Limits = [0 Inf];
            app.Dp.Value = 2;
            app.PosicinenXEditFieldLabel = uilabel(app.UIFigure);
            app.PosicinenXEditFieldLabel.Text = 'Posición en X';
            app.Xqp = uieditfield(app.UIFigure, 'numeric');
            app.Xqp.Limits = [-20 20];
            app.Xqp.Value = -6;
            app.CablePositivoLabel = uilabel(app.UIFigure);
            app.CablePositivoLabel.Text = 'Cable Positivo';
            app.CableNegativoLabel = uilabel(app.UIFigure);
            app.CableNegativoLabel.Text = 'Cable Negativo';
            app.LongitudMEditField_2Label = uilabel(app.UIFigure);
            app.LongitudMEditField_2Label.Text = 'Longitud(M)';
            app.Ln = uieditfield(app.UIFigure, 'numeric');
            app.Ln.Limits = [-20 20];
            app.Ln.Value = 2;
            app.DensidaddecargaCMEditField_2Label = uilabel(app.UIFigure);
            app.DensidaddecargaCMEditField_2Label.Text = 'Densidad de carga(C/M)';
            app.Dn = uieditfield(app.UIFigure, 'numeric');
            app.Dn.Limits = [-Inf 0];
            app.Dn.Value = -2;
            app.PosicinenXEditField_2Label = uilabel(app.UIFigure);
            app.PosicinenXEditField_2Label.Text = 'Posición en X';
            app.xn = uieditfield(app.UIFigure, 'numeric');
            app.xn.Limits = [-20 20];
            app.xn.Value = 3;
            app.SimularPorintegracinButton = uibutton(app.UIFigure, 'push');
            app.SimularPorintegracinButton.ButtonPushedFcn = createCallbackFcn(app, @SimularPorintegracinButtonPushed, true);
            app.SimularPorintegracinButton.Text = 'Simular (Por integración)';
            app.SimularPoraproximacinButton = uibutton(app.UIFigure, 'push');
            app.SimularPoraproximacinButton.ButtonPushedFcn = createCallbackFcn(app, @SimularPoraproximacinButtonPushed, true);
            app.SimularPoraproximacinButton.Text = 'Simular (Por aproximación)';
            app.InicioLabel = uilabel(app.UIFigure);
            app.InicioLabel.Text = {'Inicio'; ''};
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status: ';
            app.TiempodeejecucinsLabel = uilabel(app.UIFigure);
            app.TiempodeejecucinsLabel.Text = 'Tiempo de ejecución (s):';
            app.timeE = uilabel(app.UIFigure);
            app.timeE.Text = '0';
            app.Equipo4Label = uilabel(app.UIFigure);
            app.Equipo4Label.Text = 'Equipo 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimuladorFINAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end